classdef studying1_stem_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PLOTButton  matlab.ui.control.Button
        DropDown    matlab.ui.control.DropDown
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            value = app.DropDown.Value;
            w = 2;
            T = 2*pi/w;
            t = linspace (0,2*T);
            A = 5;
            phi = 0;
            f = A*sin(w*t + phi);


            if (value == 'plot')
                plot(app.UIAxes, t, f);
            elseif (value == 'stem')
                stem(app.UIAxes, t, f);
            elseif (value == 'Choose')
                        cla(app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Choose', 'stem', 'plot'};
            app.DropDown.Value = 'Choose';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = studying1_stem_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end