classdef studying2_u1u2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EXITButton            matlab.ui.control.Button
        CLEARGRAPHSButton     matlab.ui.control.Button
        DefineSignalsPanel    matlab.ui.container.Panel
        PlotButton_3          matlab.ui.control.Button
        utEditField           matlab.ui.control.EditField
        utEditFieldLabel      matlab.ui.control.Label
        DefineFunctionsPanel  matlab.ui.container.Panel
        PlotButton_2          matlab.ui.control.Button
        PlotButton            matlab.ui.control.Button
        u2tEditField          matlab.ui.control.EditField
        u2tEditFieldLabel     matlab.ui.control.Label
        u1tEditField          matlab.ui.control.EditField
        u1tEditFieldLabel     matlab.ui.control.Label
        radsLabel             matlab.ui.control.Label
        wEditField            matlab.ui.control.NumericEditField
        wEditFieldLabel       matlab.ui.control.Label
        sLabel                matlab.ui.control.Label
        incLabel              matlab.ui.control.Label
        lvLabel               matlab.ui.control.Label
        ivLabel               matlab.ui.control.Label
        tLabel                matlab.ui.control.Label
        incEditField          matlab.ui.control.NumericEditField
        lvEditField           matlab.ui.control.NumericEditField
        ivEditField           matlab.ui.control.NumericEditField
        UIAxesu1              matlab.ui.control.UIAxes
        UIAxesu3              matlab.ui.control.UIAxes
        UIAxesu2              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            iv = app.ivEditField.Value;
            lv = app.lvEditField.Value;
            inc = app.incEditField.Value;
            w = app.wEditField.Value;
            eq = app.u1tEditField.Value;

            T = 2*pi/w;
            t = linspace(iv,lv,inc);
            
            f = str2func(['@(w,t)' eq]);
            y = f(w,t);
            plot(app.UIAxesu1, t, y);
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
             iv = app.ivEditField.Value;
            lv = app.lvEditField.Value;
            inc = app.incEditField.Value;
            w = app.wEditField.Value;
            eq2 = app.u2tEditField.Value;

            T = 2*pi/w;
            t = linspace(iv,lv,inc);
            
            f2 = str2func(['@(w,t)' eq2]);
            y2 = f2(w,t);
            plot(app.UIAxesu2, t, y2);
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
              iv = app.ivEditField.Value;
            lv = app.lvEditField.Value;
            inc = app.incEditField.Value;
            w = app.wEditField.Value;
            eq3 = app.u3tEditField.Value;
            u1 = y;
            u2 = y2;
% toplama çıkarma vs için if else yapısı oluştur gitsin. katsayıda
% eklersin. guiye eklersin.
            T = 2*pi/w;
            t = linspace(iv,lv,inc);
            
            f3 = str2func(['@(u1 u2)' eq3]);
            y3 = f2(w,t);
            plot(app.UIAxesu2, t, y3);
        end

        % Button pushed function: CLEARGRAPHSButton
        function CLEARGRAPHSButtonPushed(app, event)
            cla(app.UIAxesu1);
            cla(app.UIAxesu2);
            cla(app.UIAxesu3);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesu2 = uiaxes(app.UIFigure);
            title(app.UIAxesu2, 'Title')
            xlabel(app.UIAxesu2, 'X')
            ylabel(app.UIAxesu2, 'Y')
            zlabel(app.UIAxesu2, 'Z')
            app.UIAxesu3 = uiaxes(app.UIFigure);
            title(app.UIAxesu3, 'Title')
            xlabel(app.UIAxesu3, 'X')
            ylabel(app.UIAxesu3, 'Y')
            zlabel(app.UIAxesu3, 'Z')
            app.UIAxesu1 = uiaxes(app.UIFigure);
            title(app.UIAxesu1, 'Title')
            xlabel(app.UIAxesu1, 'X')
            ylabel(app.UIAxesu1, 'Y')
            zlabel(app.UIAxesu1, 'Z')
            app.DefineFunctionsPanel = uipanel(app.UIFigure);
            app.DefineFunctionsPanel.Title = 'Define Functions';
            app.ivEditField = uieditfield(app.DefineFunctionsPanel, 'numeric');
            app.ivEditField.AllowEmpty = 'on';
            app.ivEditField.Value = [];
            app.lvEditField = uieditfield(app.DefineFunctionsPanel, 'numeric');
            app.lvEditField.AllowEmpty = 'on';
            app.lvEditField.Value = [];
            app.incEditField = uieditfield(app.DefineFunctionsPanel, 'numeric');
            app.incEditField.AllowEmpty = 'on';
            app.incEditField.Value = [];
            app.tLabel = uilabel(app.DefineFunctionsPanel);
            app.tLabel.Text = 't:';
            app.ivLabel = uilabel(app.DefineFunctionsPanel);
            app.ivLabel.Text = 'iv';
            app.lvLabel = uilabel(app.DefineFunctionsPanel);
            app.lvLabel.Text = 'lv';
            app.incLabel = uilabel(app.DefineFunctionsPanel);
            app.incLabel.Text = 'inc';
            app.sLabel = uilabel(app.DefineFunctionsPanel);
            app.sLabel.Text = 's';
            app.wEditFieldLabel = uilabel(app.DefineFunctionsPanel);
            app.wEditFieldLabel.Text = 'w:';
            app.wEditField = uieditfield(app.DefineFunctionsPanel, 'numeric');
            app.radsLabel = uilabel(app.DefineFunctionsPanel);
            app.radsLabel.Text = 'rad/s';
            app.u1tEditFieldLabel = uilabel(app.DefineFunctionsPanel);
            app.u1tEditFieldLabel.Text = 'u1(t) = ';
            app.u1tEditField = uieditfield(app.DefineFunctionsPanel, 'text');
            app.u1tEditField.Value = '2*sin(w*t)';
            app.u2tEditFieldLabel = uilabel(app.DefineFunctionsPanel);
            app.u2tEditFieldLabel.Text = 'u2(t)= ';
            app.u2tEditField = uieditfield(app.DefineFunctionsPanel, 'text');
            app.u2tEditField.Value = 'cos(4*w*t)';
            app.PlotButton = uibutton(app.DefineFunctionsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.DefineFunctionsPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.DefineSignalsPanel = uipanel(app.UIFigure);
            app.DefineSignalsPanel.Title = 'Define Signals';
            app.utEditFieldLabel = uilabel(app.DefineSignalsPanel);
            app.utEditFieldLabel.Text = 'u(t)';
            app.utEditField = uieditfield(app.DefineSignalsPanel, 'text');
            app.utEditField.Value = 'u1+u2';
            app.PlotButton_3 = uibutton(app.DefineSignalsPanel, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.CLEARGRAPHSButton = uibutton(app.UIFigure, 'push');
            app.CLEARGRAPHSButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARGRAPHSButtonPushed, true);
            app.CLEARGRAPHSButton.Text = 'CLEAR GRAPHS';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = studying2_u1u2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end