classdef uygulama1_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI1PlottingUIFigure  matlab.ui.Figure
        Button                matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            w = 1;
            T = 2*pi/w;
            t = linspace(0,T);
            u = 5*sin(w*t);
            
            plot(app.UIAxes, t,u);
            app.UIAxes.XDir = 'reverse'; 
% Buraya yazman gerekiyor ki elde ettiğin sonuç grafikte gözüksün. Yoksa
% diğer hususlarda gelmez
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.XLim = [0 6.30];
            % x basarak kapatabilirmiş kullanıcı :D
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            set(app.UIAxes, 'XGrid', 'on');
            set(app.UIAxes, 'YGrid', 'on');
            set(app.UIAxes, 'XDir', 'reverse');
            app.UIAxes.XDir = 'reverse';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI1PlottingUIFigure = uifigure('Visible', 'off');
            app.GUI1PlottingUIFigure.Name = 'GUI 1 Plotting';
            app.UIAxes = uiaxes(app.GUI1PlottingUIFigure);
            title(app.UIAxes, 'Graph of the Function')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'u(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button = uibutton(app.GUI1PlottingUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.GUI1PlottingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulama1_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI1PlottingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI1PlottingUIFigure)
        end
    end
end