classdef uygulama4_button_group < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KAPATButton             matlab.ui.control.Button
        CevabizButton           matlab.ui.control.Button
        ansEditField            matlab.ui.control.EditField
        SistemCevabButtonGroup  matlab.ui.container.ButtonGroup
        NyquistButton           matlab.ui.control.RadioButton
        StepButton              matlab.ui.control.RadioButton
        paydaEditField          matlab.ui.control.EditField
        payEditField            matlab.ui.control.EditField
        PaydaLabel              matlab.ui.control.Label
        PayLabel                matlab.ui.control.Label
        TransferFunctionLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: SistemCevabButtonGroup
        function SistemCevabButtonGroupSelectionChanged(app, event)
            selectedButton = app.SistemCevabButtonGroup.SelectedObject;
            
            value = selectedButton.Text;
        switch value
        case 'Step'
            app.ansEditField.Value = 'Adım cevabı :';
            case 'Nyquist'
                app.ansEditField.Value = 'Nyquist diyagramı';
        end
        end

        % Button pushed function: CevabizButton
        function CevabizButtonPushed(app, event)
            selectedButton = app.SistemCevabButtonGroup.SelectedObject;
            value = selectedButton.Text;
            % str2num kullanılmalı ki numaraya dönsün.
            pay = str2num(app.payEditField.Value);
            payda = str2num(app.paydaEditField.Value);
            % tf?
            sys = tf(pay,payda);

            switch value
                case 'Step'
                    [y t] = step(sys);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, 'Adım Cevabı');
                    xlabel(app.UIAxes, 'Zaman (saniye)');
                    ylabel(app.UIAxes, 'y(t)');
                case 'Nyquist'
                     [re im] = step(sys);
                    plot(app.UIAxes, re, im);
                    title(app.UIAxes, 'Nyquist Diyagramı');
                    xlabel(app.UIAxes, 'Gerçel Eksen');
                    ylabel(app.UIAxes, 'Sanal Eksen');
            end
        end

        % Button pushed function: KAPATButton
        function KAPATButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TransferFunctionLabel = uilabel(app.UIFigure);
            app.TransferFunctionLabel.Text = 'Transfer Function';
            app.PayLabel = uilabel(app.UIFigure);
            app.PayLabel.Text = 'Pay:';
            app.PaydaLabel = uilabel(app.UIFigure);
            app.PaydaLabel.Text = 'Payda:';
            app.payEditField = uieditfield(app.UIFigure, 'text');
            app.paydaEditField = uieditfield(app.UIFigure, 'text');
            app.SistemCevabButtonGroup = uibuttongroup(app.UIFigure);
            app.SistemCevabButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SistemCevabButtonGroupSelectionChanged, true);
            app.SistemCevabButtonGroup.Title = 'Sistem Cevabı';
            app.StepButton = uiradiobutton(app.SistemCevabButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.NyquistButton = uiradiobutton(app.SistemCevabButtonGroup);
            app.NyquistButton.Text = 'Nyquist';
            app.ansEditField = uieditfield(app.UIFigure, 'text');
            app.ansEditField.Value = 'Adım Cevabı:';
            app.CevabizButton = uibutton(app.UIFigure, 'push');
            app.CevabizButton.ButtonPushedFcn = createCallbackFcn(app, @CevabizButtonPushed, true);
            app.CevabizButton.Text = 'Cevabı Çiz';
            app.KAPATButton = uibutton(app.UIFigure, 'push');
            app.KAPATButton.ButtonPushedFcn = createCallbackFcn(app, @KAPATButtonPushed, true);
            app.KAPATButton.Text = 'KAPAT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulama4_button_group

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end