classdef uygulama5_tab_group_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        LineerFonksiyonTab     matlab.ui.container.Tab
        AEditFieldLinear       matlab.ui.control.NumericEditField
        ALabel                 matlab.ui.control.Label
        BEditFieldLinear       matlab.ui.control.NumericEditField
        BEditFieldLabel_3      matlab.ui.control.Label
        lvEditFieldLinear      matlab.ui.control.NumericEditField
        lvEditFieldLabel_2     matlab.ui.control.Label
        invEditFieldLinear     matlab.ui.control.NumericEditField
        invEditFieldLabel      matlab.ui.control.Label
        ivEditFieldLinear      matlab.ui.control.NumericEditField
        ivEditFieldLabel_2     matlab.ui.control.Label
        ExitButtonLinear       matlab.ui.control.Button
        PlotButtonLinear       matlab.ui.control.Button
        XDeerleriiinLabel_2    matlab.ui.control.Label
        KatsaylarLabel_3       matlab.ui.control.Label
        fxaxbLabel             matlab.ui.control.Label
        UIAxesLinear           matlab.ui.control.UIAxes
        KuadratikFonksiyonTab  matlab.ui.container.Tab
        CEditFieldKuadratik    matlab.ui.control.NumericEditField
        CEditFieldLabel        matlab.ui.control.Label
        BEditFieldKuadratik    matlab.ui.control.NumericEditField
        BEditField_3Label      matlab.ui.control.Label
        AEditFieldKuadratik    matlab.ui.control.NumericEditField
        AEditField_2Label      matlab.ui.control.Label
        lvEditFieldKuadratik   matlab.ui.control.NumericEditField
        lvEditField_2Label     matlab.ui.control.Label
        invEditFieldKuadratik  matlab.ui.control.NumericEditField
        invEditField_2Label    matlab.ui.control.Label
        ivEditFieldKuadratik   matlab.ui.control.NumericEditField
        ivEditField_2Label     matlab.ui.control.Label
        ExitButtonKuadratik    matlab.ui.control.Button
        PlotButtonKuadratik    matlab.ui.control.Button
        XDeerleriiinLabel_3    matlab.ui.control.Label
        KatsaylarLabel_4       matlab.ui.control.Label
        fxax2bxcLabel          matlab.ui.control.Label
        UIAxesKuadratik        matlab.ui.control.UIAxes
        SinsFonksiyonuTab_2    matlab.ui.container.Tab
        AEditFieldSinus        matlab.ui.control.NumericEditField
        AEditField_3Label      matlab.ui.control.Label
        lvEditFieldSinus       matlab.ui.control.NumericEditField
        lvEditField_3Label     matlab.ui.control.Label
        invEditFieldSinus      matlab.ui.control.NumericEditField
        invEditField_3Label    matlab.ui.control.Label
        ivEditFieldSinus       matlab.ui.control.NumericEditField
        ivEditField_3Label     matlab.ui.control.Label
        ExitButtonSinus        matlab.ui.control.Button
        PlotButtonSinus        matlab.ui.control.Button
        XDeerleriiinLabel      matlab.ui.control.Label
        KatsaylarLabel_2       matlab.ui.control.Label
        fxAsinxLabel_2         matlab.ui.control.Label
        UIAxesSinus            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButtonSinus
        function PlotButtonSinusPushed(app, event)
            a = app.AEditFieldSinus.Value;
            

            iv = app.ivEditFieldSinus.Value;
            inc = app.invEditFieldSinus.Value;
            lv = app.lvEditFieldSinus.Value;

            x = linspace(iv,lv,inc);
            f = a*sin(x);

            plot(app.UIAxesSinus, x, f);
          

        end

        % Button pushed function: PlotButtonLinear
        function PlotButtonLinearPushed(app, event)
            a = app.AEditFieldLinear.Value;
            b = app.BEditFieldLinear.Value;

            iv = app.ivEditFieldLinear.Value;
            inc = app.invEditFieldLinear.Value;
            lv = app.lvEditFieldLinear.Value;

            x = linspace(iv,lv,inc);
            f = a*x+b;

            plot(app.UIAxesLinear, x, f);
          

        end

        % Button pushed function: PlotButtonKuadratik
        function PlotButtonKuadratikPushed(app, event)
            a = app.AEditFieldKuadratik.Value;
            b = app.BEditFieldKuadratik.Value;
            c = app.CEditFieldKuadratik.Value;

            iv = app.ivEditFieldKuadratik.Value;
            inc = app.invEditFieldKuadratik.Value;
            lv = app.lvEditFieldKuadratik.Value;

            x = linspace(iv,lv,inc);
            f = a*x.^2+b*x+c;

            plot(app.UIAxesKuadratik, x, f);
          
        end

        % Button pushed function: ExitButtonLinear
        function ExitButtonLinearPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: ExitButtonKuadratik
        function ExitButtonKuadratikPushed(app, event)
              close(app.UIFigure);
        end

        % Button pushed function: ExitButtonSinus
        function ExitButtonSinusPushed(app, event)
              close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LineerFonksiyonTab = uitab(app.TabGroup);
            app.LineerFonksiyonTab.Title = 'Lineer Fonksiyon';
            app.UIAxesLinear = uiaxes(app.LineerFonksiyonTab);
            title(app.UIAxesLinear, 'Title')
            xlabel(app.UIAxesLinear, 'X')
            ylabel(app.UIAxesLinear, 'Y')
            zlabel(app.UIAxesLinear, 'Z')
            app.fxaxbLabel = uilabel(app.LineerFonksiyonTab);
            app.fxaxbLabel.Text = 'f(x) = a*x+b';
            app.KatsaylarLabel_3 = uilabel(app.LineerFonksiyonTab);
            app.KatsaylarLabel_3.Text = 'Katsayılar';
            app.XDeerleriiinLabel_2 = uilabel(app.LineerFonksiyonTab);
            app.XDeerleriiinLabel_2.Text = 'X Değerleri için';
            app.PlotButtonLinear = uibutton(app.LineerFonksiyonTab, 'push');
            app.PlotButtonLinear.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonLinearPushed, true);
            app.PlotButtonLinear.Text = 'Plot';
            app.ExitButtonLinear = uibutton(app.LineerFonksiyonTab, 'push');
            app.ExitButtonLinear.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonLinearPushed, true);
            app.ExitButtonLinear.Text = 'Exit';
            app.ivEditFieldLabel_2 = uilabel(app.LineerFonksiyonTab);
            app.ivEditFieldLabel_2.Text = 'iv';
            app.ivEditFieldLinear = uieditfield(app.LineerFonksiyonTab, 'numeric');
            app.ivEditFieldLinear.AllowEmpty = 'on';
            app.ivEditFieldLinear.Value = [];
            app.invEditFieldLabel = uilabel(app.LineerFonksiyonTab);
            app.invEditFieldLabel.Text = 'inv';
            app.invEditFieldLinear = uieditfield(app.LineerFonksiyonTab, 'numeric');
            app.invEditFieldLinear.AllowEmpty = 'on';
            app.invEditFieldLinear.Value = [];
            app.lvEditFieldLabel_2 = uilabel(app.LineerFonksiyonTab);
            app.lvEditFieldLabel_2.Text = 'lv';
            app.lvEditFieldLinear = uieditfield(app.LineerFonksiyonTab, 'numeric');
            app.lvEditFieldLinear.AllowEmpty = 'on';
            app.lvEditFieldLinear.Value = [];
            app.BEditFieldLabel_3 = uilabel(app.LineerFonksiyonTab);
            app.BEditFieldLabel_3.Text = 'B:';
            app.BEditFieldLinear = uieditfield(app.LineerFonksiyonTab, 'numeric');
            app.BEditFieldLinear.AllowEmpty = 'on';
            app.BEditFieldLinear.Value = [];
            app.ALabel = uilabel(app.LineerFonksiyonTab);
            app.ALabel.Text = 'A:';
            app.AEditFieldLinear = uieditfield(app.LineerFonksiyonTab, 'numeric');
            app.AEditFieldLinear.AllowEmpty = 'on';
            app.AEditFieldLinear.Value = [];
            app.KuadratikFonksiyonTab = uitab(app.TabGroup);
            app.KuadratikFonksiyonTab.Title = 'Kuadratik Fonksiyon';
            app.UIAxesKuadratik = uiaxes(app.KuadratikFonksiyonTab);
            title(app.UIAxesKuadratik, 'Title')
            xlabel(app.UIAxesKuadratik, 'X')
            ylabel(app.UIAxesKuadratik, 'Y')
            zlabel(app.UIAxesKuadratik, 'Z')
            app.fxax2bxcLabel = uilabel(app.KuadratikFonksiyonTab);
            app.fxax2bxcLabel.Text = 'f(x) = a*x^2+b*x+c';
            app.KatsaylarLabel_4 = uilabel(app.KuadratikFonksiyonTab);
            app.KatsaylarLabel_4.Text = 'Katsayılar';
            app.XDeerleriiinLabel_3 = uilabel(app.KuadratikFonksiyonTab);
            app.XDeerleriiinLabel_3.Text = 'X Değerleri için';
            app.PlotButtonKuadratik = uibutton(app.KuadratikFonksiyonTab, 'push');
            app.PlotButtonKuadratik.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonKuadratikPushed, true);
            app.PlotButtonKuadratik.Text = 'Plot';
            app.ExitButtonKuadratik = uibutton(app.KuadratikFonksiyonTab, 'push');
            app.ExitButtonKuadratik.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonKuadratikPushed, true);
            app.ExitButtonKuadratik.Text = 'Exit';
            app.ivEditField_2Label = uilabel(app.KuadratikFonksiyonTab);
            app.ivEditField_2Label.Text = 'iv';
            app.ivEditFieldKuadratik = uieditfield(app.KuadratikFonksiyonTab, 'numeric');
            app.ivEditFieldKuadratik.AllowEmpty = 'on';
            app.ivEditFieldKuadratik.Value = [];
            app.invEditField_2Label = uilabel(app.KuadratikFonksiyonTab);
            app.invEditField_2Label.Text = 'inv';
            app.invEditFieldKuadratik = uieditfield(app.KuadratikFonksiyonTab, 'numeric');
            app.invEditFieldKuadratik.AllowEmpty = 'on';
            app.invEditFieldKuadratik.Value = [];
            app.lvEditField_2Label = uilabel(app.KuadratikFonksiyonTab);
            app.lvEditField_2Label.Text = 'lv';
            app.lvEditFieldKuadratik = uieditfield(app.KuadratikFonksiyonTab, 'numeric');
            app.lvEditFieldKuadratik.AllowEmpty = 'on';
            app.lvEditFieldKuadratik.Value = [];
            app.AEditField_2Label = uilabel(app.KuadratikFonksiyonTab);
            app.AEditField_2Label.Text = 'A:';
            app.AEditFieldKuadratik = uieditfield(app.KuadratikFonksiyonTab, 'numeric');
            app.AEditFieldKuadratik.AllowEmpty = 'on';
            app.AEditFieldKuadratik.Value = [];
            app.BEditField_3Label = uilabel(app.KuadratikFonksiyonTab);
            app.BEditField_3Label.Text = 'B:';
            app.BEditFieldKuadratik = uieditfield(app.KuadratikFonksiyonTab, 'numeric');
            app.BEditFieldKuadratik.AllowEmpty = 'on';
            app.BEditFieldKuadratik.Value = [];
            app.CEditFieldLabel = uilabel(app.KuadratikFonksiyonTab);
            app.CEditFieldLabel.Text = 'C:';
            app.CEditFieldKuadratik = uieditfield(app.KuadratikFonksiyonTab, 'numeric');
            app.CEditFieldKuadratik.AllowEmpty = 'on';
            app.CEditFieldKuadratik.Value = [];
            app.SinsFonksiyonuTab_2 = uitab(app.TabGroup);
            app.SinsFonksiyonuTab_2.Title = 'Sinüs Fonksiyonu';
            app.UIAxesSinus = uiaxes(app.SinsFonksiyonuTab_2);
            title(app.UIAxesSinus, 'Title')
            xlabel(app.UIAxesSinus, 'X')
            ylabel(app.UIAxesSinus, 'Y')
            zlabel(app.UIAxesSinus, 'Z')
            app.fxAsinxLabel_2 = uilabel(app.SinsFonksiyonuTab_2);
            app.fxAsinxLabel_2.Text = 'f(x) = A sin(x)';
            app.KatsaylarLabel_2 = uilabel(app.SinsFonksiyonuTab_2);
            app.KatsaylarLabel_2.Text = 'Katsayılar';
            app.XDeerleriiinLabel = uilabel(app.SinsFonksiyonuTab_2);
            app.XDeerleriiinLabel.Text = 'X Değerleri için';
            app.PlotButtonSinus = uibutton(app.SinsFonksiyonuTab_2, 'push');
            app.PlotButtonSinus.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonSinusPushed, true);
            app.PlotButtonSinus.Text = 'Plot';
            app.ExitButtonSinus = uibutton(app.SinsFonksiyonuTab_2, 'push');
            app.ExitButtonSinus.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonSinusPushed, true);
            app.ExitButtonSinus.Text = 'Exit';
            app.ivEditField_3Label = uilabel(app.SinsFonksiyonuTab_2);
            app.ivEditField_3Label.Text = 'iv';
            app.ivEditFieldSinus = uieditfield(app.SinsFonksiyonuTab_2, 'numeric');
            app.ivEditFieldSinus.AllowEmpty = 'on';
            app.ivEditFieldSinus.Value = [];
            app.invEditField_3Label = uilabel(app.SinsFonksiyonuTab_2);
            app.invEditField_3Label.Text = 'inv';
            app.invEditFieldSinus = uieditfield(app.SinsFonksiyonuTab_2, 'numeric');
            app.invEditFieldSinus.AllowEmpty = 'on';
            app.invEditFieldSinus.Value = [];
            app.lvEditField_3Label = uilabel(app.SinsFonksiyonuTab_2);
            app.lvEditField_3Label.Text = 'lv';
            app.lvEditFieldSinus = uieditfield(app.SinsFonksiyonuTab_2, 'numeric');
            app.lvEditFieldSinus.AllowEmpty = 'on';
            app.lvEditFieldSinus.Value = [];
            app.AEditField_3Label = uilabel(app.SinsFonksiyonuTab_2);
            app.AEditField_3Label.Text = 'A:';
            app.AEditFieldSinus = uieditfield(app.SinsFonksiyonuTab_2, 'numeric');
            app.AEditFieldSinus.AllowEmpty = 'on';
            app.AEditFieldSinus.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulama5_tab_group_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end