classdef uygulama6_switch_lamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Lamp             matlab.ui.control.Lamp
        incLabel         matlab.ui.control.Label
        lvLabel          matlab.ui.control.Label
        ivLabel          matlab.ui.control.Label
        lvEditField      matlab.ui.control.NumericEditField
        incEditField     matlab.ui.control.NumericEditField
        ivEditField      matlab.ui.control.NumericEditField
        xLabel           matlab.ui.control.Label
        AEditField       matlab.ui.control.NumericEditField
        AEditFieldLabel  matlab.ui.control.Label
        ExitButton       matlab.ui.control.Button
        PlotButton       matlab.ui.control.Button
        GridSwitch       matlab.ui.control.Switch
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Başlangıçta rengin gri olması için bunu ekliyoruz. Ayrıca 0 0
            % 0 rgb kodu siyahı verirken 0.5 0.5 0.5 griyi verir.
            app.GridSwitch.Value = "Off";
            app.Lamp.Color = [0.5 0.5 0.5];
        end

        % Value changed function: GridSwitch
        function GridSwitchValueChanged(app, event)
            value = app.GridSwitch.Value;
      
            if strcmp(value, 'Off')
                app.Lamp.Color = [0.5 0.5 0.5];
            else 
                app.Lamp.Color = [0 1 0];

            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a = app.AEditField.Value;

            iv = app.ivEditField.Value;
            lv = app.lvEditField.Value;
            inc = app.incEditField.Value;

            x = linspace(iv,lv,inc);
            f = a*sin(x);
            % Off olmalı off ya da on değil.
            if strcmp(app.GridSwitch.Value, "Off")
               plot(app.UIAxes, x ,f, "Color", "red", "LineWidth", 2.5);
               grid (app.UIAxes, "Off");
            else
                plot(app.UIAxes, x ,f, "Color", "red", "LineWidth", 2.5);
               grid (app.UIAxes, "On");
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridSwitch = uiswitch(app.UIFigure, 'slider');
            app.GridSwitch.ValueChangedFcn = createCallbackFcn(app, @GridSwitchValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'PLOT';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'EXIT';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A:';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.AllowEmpty = 'on';
            app.AEditField.Value = [];
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x:';
            app.ivEditField = uieditfield(app.UIFigure, 'numeric');
            app.ivEditField.RoundFractionalValues = 'on';
            app.ivEditField.AllowEmpty = 'on';
            app.ivEditField.Value = [];
            app.incEditField = uieditfield(app.UIFigure, 'numeric');
            app.incEditField.RoundFractionalValues = 'on';
            app.incEditField.AllowEmpty = 'on';
            app.incEditField.Value = [];
            app.lvEditField = uieditfield(app.UIFigure, 'numeric');
            app.lvEditField.AllowEmpty = 'on';
            app.lvEditField.Value = [];
            app.ivLabel = uilabel(app.UIFigure);
            app.ivLabel.Text = 'iv';
            app.lvLabel = uilabel(app.UIFigure);
            app.lvLabel.Text = 'lv';
            app.incLabel = uilabel(app.UIFigure);
            app.incLabel.Text = 'inc';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulama6_switch_lamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end