classdef uygulama7_semicirculargauge_slider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_2  matlab.ui.control.EditField
        Lamp         matlab.ui.control.Lamp
        EditField    matlab.ui.control.NumericEditField
        Gauge        matlab.ui.control.SemicircularGauge
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
     
        end

        % Value changing function: Slider
        function ValueChangingFcn(app, event)
            value = event.Value;
                   app.Gauge.Value = value;
            app.EditField.Value = value;

            if value >= 0 && value <=50
                app.Lamp.Color = 'g';
                app.EditField_2.Value = 'Düşük';
            elseif value > 50 && value <= 75
                app.Lamp.Color = 'y';
                app.EditField_2.Value = 'Orta';
            else 
                app.Lamp.Color = 'r';
                app.EditField_2.Value = 'Yüksek';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @ValueChangingFcn, true);
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp = uilamp(app.UIFigure);
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulama7_semicirculargauge_slider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end