classdef uygulama8_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField                      matlab.ui.control.EditField
        EKLEButton                     matlab.ui.control.Button
        SatrEkleLabel                  matlab.ui.control.Label
        KapatButton                    matlab.ui.control.Button
        TablodaGosterButton            matlab.ui.control.Button
        SutunEditField                 matlab.ui.control.NumericEditField
        SutunEditFieldLabel            matlab.ui.control.Label
        SatirEditField                 matlab.ui.control.NumericEditField
        SatirEditFieldLabel            matlab.ui.control.Label
        SatrveStunSaylarnGirinizLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        MatrisTipiniSeinizLabel        matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TablodaGosterButton
        function TablodaGosterButtonPushed(app, event)
            sat = app.SatirEditField.Value;
            sut = app.SutunEditField.Value;
            deger = app.DropDown.Value;

            switch deger
                case 'zeros';
                    sonuc = zeros(sat,sut);
                    app.UITable.Data = [sonuc];
                case 'ones';
                    sonuc = ones(sat,sut);
                    app.UITable.Data = [sonuc];

                case 'eye';
                    sonuc = eye(sat,sut);
                    app.UITable.Data = [sonuc];

                case 'rand';
                    sonuc = rand(sat,sut);
                    app.UITable.Data = [sonuc];

                case 'random';
                    sonuc = randn(sat,sut);
                    app.UITable.Data = [sonuc];
            end

        end

        % Button pushed function: KapatButton
        function KapatButtonPushed(app, event)
            delete.app;

        end

        % Button pushed function: EKLEButton
        function EKLEButtonPushed(app, event)
            t = app.UITable.Data;
            values = app.EditField.Value;

            app.UITable.Data = [t; str2num(values)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MatrisTipiniSeinizLabel = uilabel(app.UIFigure);
            app.MatrisTipiniSeinizLabel.Text = 'Matris Tipini Seçiniz:';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'zeros', 'ones', 'eye', 'rand', 'random'};
            app.DropDown.Value = 'zeros';
            app.SatrveStunSaylarnGirinizLabel = uilabel(app.UIFigure);
            app.SatrveStunSaylarnGirinizLabel.Text = 'Satır ve Sütun Sayılarını Giriniz:';
            app.SatirEditFieldLabel = uilabel(app.UIFigure);
            app.SatirEditFieldLabel.Text = 'Satir:';
            app.SatirEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatirEditField.AllowEmpty = 'on';
            app.SatirEditField.Value = [];
            app.SutunEditFieldLabel = uilabel(app.UIFigure);
            app.SutunEditFieldLabel.Text = 'Sutun:';
            app.SutunEditField = uieditfield(app.UIFigure, 'numeric');
            app.SutunEditField.AllowEmpty = 'on';
            app.SutunEditField.Value = [];
            app.TablodaGosterButton = uibutton(app.UIFigure, 'push');
            app.TablodaGosterButton.ButtonPushedFcn = createCallbackFcn(app, @TablodaGosterButtonPushed, true);
            app.TablodaGosterButton.Text = 'Tabloda Goster';
            app.KapatButton = uibutton(app.UIFigure, 'push');
            app.KapatButton.ButtonPushedFcn = createCallbackFcn(app, @KapatButtonPushed, true);
            app.KapatButton.Text = 'Kapat';
            app.SatrEkleLabel = uilabel(app.UIFigure);
            app.SatrEkleLabel.Text = 'Satır Ekle:';
            app.EKLEButton = uibutton(app.UIFigure, 'push');
            app.EKLEButton.ButtonPushedFcn = createCallbackFcn(app, @EKLEButtonPushed, true);
            app.EKLEButton.Text = 'EKLE';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uygulama8_table

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end